classdef TripleS_2018_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TripleS_Fig      matlab.ui.Figure
        uipanel27        matlab.ui.container.Panel
        axes1            matlab.ui.control.UIAxes
        axes13           matlab.ui.control.UIAxes
        uipanel9         matlab.ui.container.Panel
        p13              matlab.ui.control.EditField
        p23              matlab.ui.control.EditField
        dist3            matlab.ui.control.DropDown
        text5            matlab.ui.control.Label
        p12              matlab.ui.control.EditField
        p22              matlab.ui.control.EditField
        dist2            matlab.ui.control.DropDown
        text4            matlab.ui.control.Label
        p11              matlab.ui.control.EditField
        p21              matlab.ui.control.EditField
        nave1            matlab.ui.control.EditField
        dist1            matlab.ui.control.DropDown
        J01              matlab.ui.control.EditField
        text3            matlab.ui.control.Label
        text8            matlab.ui.control.Label
        text9            matlab.ui.control.Label
        text10           matlab.ui.control.Label
        text49           matlab.ui.control.Label
        text69           matlab.ui.control.Label
        text70           matlab.ui.control.Label
        text15           matlab.ui.control.Label
        text52           matlab.ui.control.Label
        text50           matlab.ui.control.Label
        J03              matlab.ui.control.EditField
        J23              matlab.ui.control.EditField
        J13              matlab.ui.control.EditField
        J11              matlab.ui.control.EditField
        J21              matlab.ui.control.EditField
        J02              matlab.ui.control.EditField
        J12              matlab.ui.control.EditField
        J22              matlab.ui.control.EditField
        nave2            matlab.ui.control.EditField
        nave3            matlab.ui.control.EditField
        uipanel15        matlab.ui.container.Panel
        modelnum1        matlab.ui.control.DropDown
        modelnum4        matlab.ui.control.DropDown
        modelnum2        matlab.ui.control.DropDown
        linetype4        matlab.ui.control.EditField
        observe4         matlab.ui.control.DropDown
        linetype2        matlab.ui.control.EditField
        observe2         matlab.ui.control.DropDown
        linetype1        matlab.ui.control.EditField
        observe1         matlab.ui.control.DropDown
        modelnum6        matlab.ui.control.DropDown
        modelnum5        matlab.ui.control.DropDown
        modelnum3        matlab.ui.control.DropDown
        observe5         matlab.ui.control.DropDown
        linetype5        matlab.ui.control.EditField
        linetype3        matlab.ui.control.EditField
        observe3         matlab.ui.control.DropDown
        linetype6        matlab.ui.control.EditField
        observe6         matlab.ui.control.DropDown
        pushbutton4      matlab.ui.control.Button
        te_val1          matlab.ui.control.EditField
        te_val2          matlab.ui.control.EditField
        te_val3          matlab.ui.control.EditField
        text91           matlab.ui.control.Label
        text92           matlab.ui.control.Label
        text93           matlab.ui.control.Label
        uipanel24        matlab.ui.container.Panel
        offres           matlab.ui.control.EditField
        text55           matlab.ui.control.Label
        voxsize          matlab.ui.control.EditField
        text89           matlab.ui.control.Label
        go               matlab.ui.control.Button
        text96           matlab.ui.control.Label
        text109          matlab.ui.control.Label
        text106          matlab.ui.control.Label
        Label            matlab.ui.control.Label
        segmentsequence  matlab.ui.control.Switch
        uipanel12        matlab.ui.container.Panel
        phase10          matlab.ui.control.EditField
        idealflip10      matlab.ui.control.EditField
        shape10          matlab.ui.control.DropDown
        length10         matlab.ui.control.EditField
        phase09          matlab.ui.control.EditField
        idealflip09      matlab.ui.control.EditField
        shape09          matlab.ui.control.DropDown
        length09         matlab.ui.control.EditField
        length08         matlab.ui.control.EditField
        shape08          matlab.ui.control.DropDown
        idealflip08      matlab.ui.control.EditField
        phase08          matlab.ui.control.EditField
        phase07          matlab.ui.control.EditField
        idealflip07      matlab.ui.control.EditField
        shape07          matlab.ui.control.DropDown
        length07         matlab.ui.control.EditField
        length06         matlab.ui.control.EditField
        shape06          matlab.ui.control.DropDown
        idealflip06      matlab.ui.control.EditField
        phase06          matlab.ui.control.EditField
        phase05          matlab.ui.control.EditField
        idealflip05      matlab.ui.control.EditField
        shape05          matlab.ui.control.DropDown
        length05         matlab.ui.control.EditField
        length04         matlab.ui.control.EditField
        shape04          matlab.ui.control.DropDown
        idealflip04      matlab.ui.control.EditField
        phase04          matlab.ui.control.EditField
        phase03          matlab.ui.control.EditField
        idealflip03      matlab.ui.control.EditField
        shape03          matlab.ui.control.DropDown
        length03         matlab.ui.control.EditField
        length02         matlab.ui.control.EditField
        idealflip02      matlab.ui.control.EditField
        phase02          matlab.ui.control.EditField
        phase01          matlab.ui.control.EditField
        shape01          matlab.ui.control.DropDown
        length01         matlab.ui.control.EditField
        text34           matlab.ui.control.Label
        text33           matlab.ui.control.Label
        text32           matlab.ui.control.Label
        text31           matlab.ui.control.Label
        text41           matlab.ui.control.Label
        grads01          matlab.ui.control.EditField
        grads02          matlab.ui.control.EditField
        grads03          matlab.ui.control.EditField
        grads04          matlab.ui.control.EditField
        grads05          matlab.ui.control.EditField
        grads06          matlab.ui.control.EditField
        grads07          matlab.ui.control.EditField
        grads08          matlab.ui.control.EditField
        grads09          matlab.ui.control.EditField
        grads10          matlab.ui.control.EditField
        text59           matlab.ui.control.Label
        idealflip01      matlab.ui.control.EditField
        pphasecyc01      matlab.ui.control.EditField
        pphasecyc02      matlab.ui.control.EditField
        pphasecyc03      matlab.ui.control.EditField
        pphasecyc04      matlab.ui.control.EditField
        pphasecyc05      matlab.ui.control.EditField
        pphasecyc06      matlab.ui.control.EditField
        pphasecyc07      matlab.ui.control.EditField
        pphasecyc08      matlab.ui.control.EditField
        pphasecyc09      matlab.ui.control.EditField
        pphasecyc10      matlab.ui.control.EditField
        text67           matlab.ui.control.Label
        PCAve            matlab.ui.control.EditField
        shape02          matlab.ui.control.DropDown
        TRep             matlab.ui.control.EditField
        text75           matlab.ui.control.Label
        step01           matlab.ui.control.EditField
        text85           matlab.ui.control.Label
        step02           matlab.ui.control.EditField
        step03           matlab.ui.control.EditField
        step04           matlab.ui.control.EditField
        step05           matlab.ui.control.EditField
        step06           matlab.ui.control.EditField
        step07           matlab.ui.control.EditField
        step08           matlab.ui.control.EditField
        step09           matlab.ui.control.EditField
        step10           matlab.ui.control.EditField
        phase11          matlab.ui.control.EditField
        idealflip11      matlab.ui.control.EditField
        shape11          matlab.ui.control.DropDown
        length11         matlab.ui.control.EditField
        grads11          matlab.ui.control.EditField
        pphasecyc11      matlab.ui.control.EditField
        step11           matlab.ui.control.EditField
        phase12          matlab.ui.control.EditField
        idealflip12      matlab.ui.control.EditField
        shape12          matlab.ui.control.DropDown
        length12         matlab.ui.control.EditField
        grads12          matlab.ui.control.EditField
        pphasecyc12      matlab.ui.control.EditField
        step12           matlab.ui.control.EditField
        text44           matlab.ui.control.Label
        text53           matlab.ui.control.Label
        steadystatenum   matlab.ui.control.EditField
        pushbutton10     matlab.ui.control.Button
        grad_lob         matlab.ui.control.EditField
        save_seq         matlab.ui.control.Button
        Retreive_Seq     matlab.ui.control.Button
        text95           matlab.ui.control.Label
        pushbutton23     matlab.ui.control.Button
        text88           matlab.ui.control.Label
        GradAve          matlab.ui.control.EditField
        text111          matlab.ui.control.Label
        type10           matlab.ui.control.DropDown
        type09           matlab.ui.control.DropDown
        type08           matlab.ui.control.DropDown
        type07           matlab.ui.control.DropDown
        type06           matlab.ui.control.DropDown
        type05           matlab.ui.control.DropDown
        type04           matlab.ui.control.DropDown
        type03           matlab.ui.control.DropDown
        type02           matlab.ui.control.DropDown
        type01           matlab.ui.control.DropDown
        type11           matlab.ui.control.DropDown
        type12           matlab.ui.control.DropDown
        SeqName          matlab.ui.control.EditField
        text88_2         matlab.ui.control.Label
        uipanel10        matlab.ui.container.Panel
        spin             matlab.ui.control.DropDown
        SDModel2         matlab.ui.control.EditField
        SDModel3         matlab.ui.control.EditField
        Load_Model1      matlab.ui.control.Button
        Unload_Model1    matlab.ui.control.Button
        Save_Model1      matlab.ui.control.Button
        New_Model1       matlab.ui.control.Button
        Load_Model2      matlab.ui.control.Button
        Unload_Model2    matlab.ui.control.Button
        Save_Model2      matlab.ui.control.Button
        New_Model2       matlab.ui.control.Button
        Load_Model3      matlab.ui.control.Button
        Unload_Model3    matlab.ui.control.Button
        Save_Model3      matlab.ui.control.Button
        SDModel1         matlab.ui.control.EditField
        New_Model3       matlab.ui.control.Button
    end


    properties (Access = private)
    end

    properties (Access = public)
        SIM;
        Paths;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function AppStartupFcn(app)
            StartupFcn(app);            
        end

        % Value changed function: grads01, grads02, grads03, 
        % grads04, grads05, grads06, grads07, grads08, grads09, 
        % grads10, grads11, grads12, idealflip01, idealflip02, 
        % idealflip03, idealflip04, idealflip05, idealflip06, 
        % idealflip07, idealflip08, idealflip09, idealflip10, 
        % idealflip11, idealflip12, length01, length02, length03, 
        % length04, length05, length06, length07, length08, 
        % length09, length10, length11, length12, phase01, phase02, 
        % phase03, phase04, phase05, phase06, phase07, phase08, 
        % phase09, phase10, phase11, phase12, pphasecyc01, 
        % pphasecyc02, pphasecyc03, pphasecyc04, pphasecyc05, 
        % pphasecyc06, pphasecyc07, pphasecyc08, pphasecyc09, 
        % pphasecyc10, pphasecyc11, pphasecyc12, shape01, shape02, 
        % shape03, shape04, shape05, shape06, shape07, shape08, 
        % shape09, shape10, shape11, shape12, step01, step02, 
        % step03, step04, step05, step06, step07, step08, step09, 
        % step10, step11, step12, type01, type02, type03, type04, 
        % type05, type06, type07, type08, type09, type10, type11, 
        % type12
        function AppSetSequenceInterface(app, event)
            SetSequenceInterface(app,event);            
        end

        % Value changed function: segmentsequence
        function AppSegSeqSwitch(app, event)
            SegmentSequenceSwitch(app,event);           
        end

        % Value changed function: J01, J02, J03, J11, J12, J13, 
        % J21, J22, J23, dist1, dist2, dist3, nave1, nave2, nave3, 
        % p11, p12, p13, p21, p22, p23
        function AppSetModInterface(app, event)
            SetModelInterface(app,event);           
        end

        % Button pushed function: go
        function AppSimulate(app, event)
            Simulate(app,event);
        end

        % Button pushed function: pushbutton4
        function AppPlotSpinEvolution(app, event)
            PlotSpinEvolution(app,event);
        end

        % Value changed function: modelnum1, modelnum2, modelnum3, 
        % modelnum4, modelnum5, modelnum6
        function AppChangeModelPlot(app, event)
            ChangeModelPlot(app,event);            
        end

        % Button pushed function: New_Model1, New_Model2, New_Model3
        function AppNewModel(app, event)
            NewModel(app,event);
        end

        % Button pushed function: Load_Model1, Load_Model2, 
        % Load_Model3
        function AppLoadModel(app, event)
            LoadModel(app,event);
        end

        % Button pushed function: Save_Model1, Save_Model2, 
        % Save_Model3
        function AppSaveModel(app, event)
            SaveModel(app,event);
        end

        % Button pushed function: Unload_Model1, Unload_Model2, 
        % Unload_Model3
        function AppUnloadModel(app, event)
            UnloadModel(app,event);
        end

        % Button pushed function: save_seq
        function AppSaveSeq(app, event)
            SaveSequence(app,event);
        end

        % Button pushed function: Retreive_Seq
        function AppLoadSeq(app, event)
            LoadSequence(app,event);
        end

        % Value changed function: offres
        function offresValueChanged(app, event)
            SetOffResonance(app,event);    
        end

        % Value changed function: TRep
        function TRepValueChanged(app, event)
            value = app.TRep.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TripleS_Fig = uifigure('Visible', 'off');
            app.TripleS_Fig.Name = 'Quantum Spin Evolution Simulator';
            app.uipanel27 = uipanel(app.TripleS_Fig);
            app.axes1 = uiaxes(app.uipanel27);
            app.axes1.Visible = 'off';
            app.axes13 = uiaxes(app.uipanel27);
            app.axes13.Visible = 'off';
            app.uipanel9 = uipanel(app.TripleS_Fig);
            app.uipanel9.Title = 'Models';
            app.p13 = uieditfield(app.uipanel9, 'text');
            app.p13.ValueChangedFcn = createCallbackFcn(app, @AppSetModInterface, true);
            app.p13.Enable = 'off';
            app.p13.Value = 'Not In Use';
            app.p23 = uieditfield(app.uipanel9, 'text');
            app.p23.ValueChangedFcn = createCallbackFcn(app, @AppSetModInterface, true);
            app.p23.Enable = 'off';
            app.p23.Value = 'Not In Use';
            app.dist3 = uidropdown(app.uipanel9);
            app.dist3.Items = {'None', 'Delta', 'Powder', 'Gaussian'};
            app.dist3.ValueChangedFcn = createCallbackFcn(app, @AppSetModInterface, true);
            app.dist3.Enable = 'off';
            app.dist3.Value = 'None';
            app.text5 = uilabel(app.uipanel9);
            app.text5.Text = '3';
            app.p12 = uieditfield(app.uipanel9, 'text');
            app.p12.ValueChangedFcn = createCallbackFcn(app, @AppSetModInterface, true);
            app.p12.Enable = 'off';
            app.p12.Value = 'Not In Use';
            app.p22 = uieditfield(app.uipanel9, 'text');
            app.p22.ValueChangedFcn = createCallbackFcn(app, @AppSetModInterface, true);
            app.p22.Enable = 'off';
            app.p22.Value = 'Not In Use';
            app.dist2 = uidropdown(app.uipanel9);
            app.dist2.Items = {'None', 'Delta', 'Powder', 'Gaussian'};
            app.dist2.ValueChangedFcn = createCallbackFcn(app, @AppSetModInterface, true);
            app.dist2.Enable = 'off';
            app.dist2.Value = 'None';
            app.text4 = uilabel(app.uipanel9);
            app.text4.Text = '2';
            app.p11 = uieditfield(app.uipanel9, 'text');
            app.p11.ValueChangedFcn = createCallbackFcn(app, @AppSetModInterface, true);
            app.p11.Enable = 'off';
            app.p11.Value = 'Not in Use';
            app.p21 = uieditfield(app.uipanel9, 'text');
            app.p21.ValueChangedFcn = createCallbackFcn(app, @AppSetModInterface, true);
            app.p21.Enable = 'off';
            app.p21.Value = 'Not in Use';
            app.nave1 = uieditfield(app.uipanel9, 'text');
            app.nave1.ValueChangedFcn = createCallbackFcn(app, @AppSetModInterface, true);
            app.nave1.Enable = 'off';
            app.nave1.Value = 'Not in Use';
            app.dist1 = uidropdown(app.uipanel9);
            app.dist1.Items = {'None', 'Delta', 'Powder', 'Gaussian', 'Uniform'};
            app.dist1.ValueChangedFcn = createCallbackFcn(app, @AppSetModInterface, true);
            app.dist1.Value = 'None';
            app.J01 = uieditfield(app.uipanel9, 'text');
            app.J01.ValueChangedFcn = createCallbackFcn(app, @AppSetModInterface, true);
            app.J01.Value = '0.0093';
            app.text3 = uilabel(app.uipanel9);
            app.text3.Text = '1';
            app.text8 = uilabel(app.uipanel9);
            app.text8.Text = 'J0:';
            app.text9 = uilabel(app.uipanel9);
            app.text9.Text = 'J1:';
            app.text10 = uilabel(app.uipanel9);
            app.text10.Text = 'J2:';
            app.text49 = uilabel(app.uipanel9);
            app.text49.Text = 'Dist:';
            app.text69 = uilabel(app.uipanel9);
            app.text69.Text = {'Spectral '; 'Density'; '(kHz)'};
            app.text70 = uilabel(app.uipanel9);
            app.text70.Text = {'RSQI'; '(kHz)'};
            app.text15 = uilabel(app.uipanel9);
            app.text15.Text = 'Mean:';
            app.text52 = uilabel(app.uipanel9);
            app.text52.Text = 'Standard Deviation:';
            app.text50 = uilabel(app.uipanel9);
            app.text50.Text = 'N. ave:';
            app.J03 = uieditfield(app.uipanel9, 'text');
            app.J03.ValueChangedFcn = createCallbackFcn(app, @AppSetModInterface, true);
            app.J03.Enable = 'off';
            app.J03.Value = 'Not In Use';
            app.J23 = uieditfield(app.uipanel9, 'text');
            app.J23.ValueChangedFcn = createCallbackFcn(app, @AppSetModInterface, true);
            app.J23.Enable = 'off';
            app.J23.Value = 'Not In Use';
            app.J13 = uieditfield(app.uipanel9, 'text');
            app.J13.ValueChangedFcn = createCallbackFcn(app, @AppSetModInterface, true);
            app.J13.Enable = 'off';
            app.J13.Value = 'Not In Use';
            app.J11 = uieditfield(app.uipanel9, 'text');
            app.J11.ValueChangedFcn = createCallbackFcn(app, @AppSetModInterface, true);
            app.J11.Value = '0.0093';
            app.J21 = uieditfield(app.uipanel9, 'text');
            app.J21.ValueChangedFcn = createCallbackFcn(app, @AppSetModInterface, true);
            app.J21.Value = '0.0093';
            app.J02 = uieditfield(app.uipanel9, 'text');
            app.J02.ValueChangedFcn = createCallbackFcn(app, @AppSetModInterface, true);
            app.J02.Enable = 'off';
            app.J02.Value = 'Not In Use';
            app.J12 = uieditfield(app.uipanel9, 'text');
            app.J12.ValueChangedFcn = createCallbackFcn(app, @AppSetModInterface, true);
            app.J12.Enable = 'off';
            app.J12.Value = 'Not In Use';
            app.J22 = uieditfield(app.uipanel9, 'text');
            app.J22.ValueChangedFcn = createCallbackFcn(app, @AppSetModInterface, true);
            app.J22.Enable = 'off';
            app.J22.Value = 'Not In Use';
            app.nave2 = uieditfield(app.uipanel9, 'text');
            app.nave2.ValueChangedFcn = createCallbackFcn(app, @AppSetModInterface, true);
            app.nave2.Enable = 'off';
            app.nave2.Value = 'Not In Use';
            app.nave3 = uieditfield(app.uipanel9, 'text');
            app.nave3.ValueChangedFcn = createCallbackFcn(app, @AppSetModInterface, true);
            app.nave3.Enable = 'off';
            app.nave3.Value = 'Not In Use';
            app.uipanel15 = uipanel(app.TripleS_Fig);
            app.uipanel15.Title = 'Display';
            app.modelnum1 = uidropdown(app.uipanel15);
            app.modelnum1.Items = {'Model 1', 'Model 2', 'Model 3', 'Don''t Use'};
            app.modelnum1.ValueChangedFcn = createCallbackFcn(app, @AppChangeModelPlot, true);
            app.modelnum1.Value = 'Model 1';
            app.modelnum4 = uidropdown(app.uipanel15);
            app.modelnum4.Items = {'Model 1', 'Model 2', 'Model 3', 'Don''t Use'};
            app.modelnum4.ValueChangedFcn = createCallbackFcn(app, @AppChangeModelPlot, true);
            app.modelnum4.Value = 'Don''t Use';
            app.modelnum2 = uidropdown(app.uipanel15);
            app.modelnum2.Items = {'Model 1', 'Model 2', 'Model 3', 'Don''t Use'};
            app.modelnum2.ValueChangedFcn = createCallbackFcn(app, @AppChangeModelPlot, true);
            app.modelnum2.Value = 'Model 1';
            app.linetype4 = uieditfield(app.uipanel15, 'text');
            app.linetype4.Enable = 'off';
            app.linetype4.Value = 'r--';
            app.observe4 = uidropdown(app.uipanel15);
            app.observe4.Items = {'T10', 'T11', 'T11s', 'T11a', 'T20', 'T21', 'T21s', 'T21a', 'T22', 'T22s', 'T22a', 'T30', 'T31', 'T31s', 'T31a', 'T32', 'T32s', 'T32a', 'T33', 'T33s', 'T33a'};
            app.observe4.Enable = 'off';
            app.observe4.Value = 'T11';
            app.linetype2 = uieditfield(app.uipanel15, 'text');
            app.linetype2.Value = 'k--';
            app.observe2 = uidropdown(app.uipanel15);
            app.observe2.Items = {'T10', 'T11', 'T11s', 'T11a', 'T20', 'T21', 'T21s', 'T21a', 'T22', 'T22s', 'T22a', 'T30', 'T31', 'T31s', 'T31a', 'T32', 'T32s', 'T32a', 'T33', 'T33s', 'T33a'};
            app.observe2.Value = 'T11';
            app.linetype1 = uieditfield(app.uipanel15, 'text');
            app.linetype1.Value = 'k-';
            app.observe1 = uidropdown(app.uipanel15);
            app.observe1.Items = {'T10', 'T11', 'T11s', 'T11a', 'T20', 'T21', 'T21s', 'T21a', 'T22', 'T22s', 'T22a', 'T30', 'T31', 'T31s', 'T31a', 'T32', 'T32s', 'T32a', 'T33', 'T33s', 'T33a'};
            app.observe1.Value = 'T10';
            app.modelnum6 = uidropdown(app.uipanel15);
            app.modelnum6.Items = {'Model 1', 'Model 2', 'Model 3', 'Don''t Use'};
            app.modelnum6.ValueChangedFcn = createCallbackFcn(app, @AppChangeModelPlot, true);
            app.modelnum6.Value = 'Don''t Use';
            app.modelnum5 = uidropdown(app.uipanel15);
            app.modelnum5.Items = {'Model 1', 'Model 2', 'Model 3', 'Don''t Use'};
            app.modelnum5.ValueChangedFcn = createCallbackFcn(app, @AppChangeModelPlot, true);
            app.modelnum5.Value = 'Don''t Use';
            app.modelnum3 = uidropdown(app.uipanel15);
            app.modelnum3.Items = {'Model 1', 'Model 2', 'Model 3', 'Don''t Use'};
            app.modelnum3.ValueChangedFcn = createCallbackFcn(app, @AppChangeModelPlot, true);
            app.modelnum3.Value = 'Don''t Use';
            app.observe5 = uidropdown(app.uipanel15);
            app.observe5.Items = {'T10', 'T11', 'T11s', 'T11a', 'T20', 'T21', 'T21s', 'T21a', 'T22', 'T22s', 'T22a', 'T30', 'T31', 'T31s', 'T31a', 'T32', 'T32s', 'T32a', 'T33', 'T33s', 'T33a'};
            app.observe5.Enable = 'off';
            app.observe5.Value = 'T10';
            app.linetype5 = uieditfield(app.uipanel15, 'text');
            app.linetype5.Enable = 'off';
            app.linetype5.Value = 'b-';
            app.linetype3 = uieditfield(app.uipanel15, 'text');
            app.linetype3.Enable = 'off';
            app.linetype3.Value = 'r-';
            app.observe3 = uidropdown(app.uipanel15);
            app.observe3.Items = {'T10', 'T11', 'T11s', 'T11a', 'T20', 'T21', 'T21s', 'T21a', 'T22', 'T22s', 'T22a', 'T30', 'T31', 'T31s', 'T31a', 'T32', 'T32s', 'T32a', 'T33', 'T33s', 'T33a'};
            app.observe3.Enable = 'off';
            app.observe3.Value = 'T10';
            app.linetype6 = uieditfield(app.uipanel15, 'text');
            app.linetype6.Enable = 'off';
            app.linetype6.Value = 'b--';
            app.observe6 = uidropdown(app.uipanel15);
            app.observe6.Items = {'T10', 'T11', 'T11s', 'T11a', 'T20', 'T21', 'T21s', 'T21a', 'T22', 'T22s', 'T22a', 'T30', 'T31', 'T31s', 'T31a', 'T32', 'T32s', 'T32a', 'T33', 'T33s', 'T33a'};
            app.observe6.Enable = 'off';
            app.observe6.Value = 'T11';
            app.pushbutton4 = uibutton(app.uipanel15, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @AppPlotSpinEvolution, true);
            app.pushbutton4.Text = 'Plot Seq';
            app.te_val1 = uieditfield(app.uipanel15, 'text');
            app.te_val1.Editable = 'off';
            app.te_val1.Value = '0';
            app.te_val2 = uieditfield(app.uipanel15, 'text');
            app.te_val2.Editable = 'off';
            app.te_val2.Enable = 'off';
            app.te_val2.Value = '0';
            app.te_val3 = uieditfield(app.uipanel15, 'text');
            app.te_val3.Editable = 'off';
            app.te_val3.Enable = 'off';
            app.te_val3.Value = '0';
            app.text91 = uilabel(app.uipanel15);
            app.text91.Text = 'Mxy1:';
            app.text92 = uilabel(app.uipanel15);
            app.text92.Text = 'Mxy2:';
            app.text93 = uilabel(app.uipanel15);
            app.text93.Text = 'Mxy3:';
            app.uipanel24 = uipanel(app.TripleS_Fig);
            app.uipanel24.Title = 'Analyze';
            app.offres = uieditfield(app.uipanel24, 'text');
            app.offres.ValueChangedFcn = createCallbackFcn(app, @offresValueChanged, true);
            app.offres.Value = '0';
            app.text55 = uilabel(app.uipanel24);
            app.text55.Text = 'Voxel Size(mm):';
            app.voxsize = uieditfield(app.uipanel24, 'text');
            app.voxsize.Value = '5';
            app.text89 = uilabel(app.uipanel24);
            app.text89.Text = 'Off Resonant Frequency:';
            app.go = uibutton(app.uipanel24, 'push');
            app.go.ButtonPushedFcn = createCallbackFcn(app, @AppSimulate, true);
            app.go.Text = 'GO';
            app.text96 = uilabel(app.uipanel24);
            app.text96.Text = 'Segment Sequence:';
            app.text109 = uilabel(app.uipanel24);
            app.text109.Enable = 'off';
            app.text109.Text = 'Ride Tx Phase:';
            app.text106 = uilabel(app.uipanel24);
            app.text106.Enable = 'off';
            app.text106.Text = 'Plot Each Average:';
            app.Label = uilabel(app.uipanel24);
            app.Label.Text = '';
            app.segmentsequence = uiswitch(app.uipanel24, 'slider');
            app.segmentsequence.Items = {'0', '1'};
            app.segmentsequence.ValueChangedFcn = createCallbackFcn(app, @AppSegSeqSwitch, true);
            app.segmentsequence.Value = '0';
            app.uipanel12 = uipanel(app.TripleS_Fig);
            app.uipanel12.Title = 'Sequence Parameters';
            app.phase10 = uieditfield(app.uipanel12, 'text');
            app.phase10.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.phase10.Visible = 'off';
            app.phase10.Value = '0';
            app.idealflip10 = uieditfield(app.uipanel12, 'text');
            app.idealflip10.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.idealflip10.Visible = 'off';
            app.idealflip10.Value = '0';
            app.shape10 = uidropdown(app.uipanel12);
            app.shape10.Items = {'Rect', 'Gaussian(a=2)', 'Gaussian(a=3)'};
            app.shape10.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.shape10.Visible = 'off';
            app.shape10.Value = 'Rect';
            app.length10 = uieditfield(app.uipanel12, 'text');
            app.length10.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.length10.Visible = 'off';
            app.length10.Value = '1';
            app.phase09 = uieditfield(app.uipanel12, 'text');
            app.phase09.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.phase09.Visible = 'off';
            app.phase09.Value = '0';
            app.idealflip09 = uieditfield(app.uipanel12, 'text');
            app.idealflip09.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.idealflip09.Visible = 'off';
            app.idealflip09.Value = '0';
            app.shape09 = uidropdown(app.uipanel12);
            app.shape09.Items = {'Rect', 'Gaussian(a=2)', 'Gaussian(a=3)'};
            app.shape09.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.shape09.Visible = 'off';
            app.shape09.Value = 'Rect';
            app.length09 = uieditfield(app.uipanel12, 'text');
            app.length09.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.length09.Visible = 'off';
            app.length09.Value = '1';
            app.length08 = uieditfield(app.uipanel12, 'text');
            app.length08.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.length08.Visible = 'off';
            app.length08.Value = '1';
            app.shape08 = uidropdown(app.uipanel12);
            app.shape08.Items = {'Rect', 'Gaussian(a=2)', 'Gaussian(a=3)'};
            app.shape08.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.shape08.Visible = 'off';
            app.shape08.Value = 'Rect';
            app.idealflip08 = uieditfield(app.uipanel12, 'text');
            app.idealflip08.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.idealflip08.Visible = 'off';
            app.idealflip08.Value = '0';
            app.phase08 = uieditfield(app.uipanel12, 'text');
            app.phase08.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.phase08.Visible = 'off';
            app.phase08.Value = '0';
            app.phase07 = uieditfield(app.uipanel12, 'text');
            app.phase07.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.phase07.Visible = 'off';
            app.phase07.Value = '0';
            app.idealflip07 = uieditfield(app.uipanel12, 'text');
            app.idealflip07.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.idealflip07.Visible = 'off';
            app.idealflip07.Value = '0';
            app.shape07 = uidropdown(app.uipanel12);
            app.shape07.Items = {'Rect', 'Gaussian(a=2)', 'Gaussian(a=3)'};
            app.shape07.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.shape07.Visible = 'off';
            app.shape07.Value = 'Rect';
            app.length07 = uieditfield(app.uipanel12, 'text');
            app.length07.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.length07.Visible = 'off';
            app.length07.Value = '1';
            app.length06 = uieditfield(app.uipanel12, 'text');
            app.length06.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.length06.Visible = 'off';
            app.length06.Value = '1';
            app.shape06 = uidropdown(app.uipanel12);
            app.shape06.Items = {'Rect', 'Gaussian(a=2)', 'Gaussian(a=3)'};
            app.shape06.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.shape06.Visible = 'off';
            app.shape06.Value = 'Rect';
            app.idealflip06 = uieditfield(app.uipanel12, 'text');
            app.idealflip06.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.idealflip06.Visible = 'off';
            app.idealflip06.Value = '0';
            app.phase06 = uieditfield(app.uipanel12, 'text');
            app.phase06.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.phase06.Visible = 'off';
            app.phase06.Value = '0';
            app.phase05 = uieditfield(app.uipanel12, 'text');
            app.phase05.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.phase05.Visible = 'off';
            app.phase05.Value = '0';
            app.idealflip05 = uieditfield(app.uipanel12, 'text');
            app.idealflip05.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.idealflip05.Visible = 'off';
            app.idealflip05.Value = '0';
            app.shape05 = uidropdown(app.uipanel12);
            app.shape05.Items = {'Rect', 'Gaussian(a=2)', 'Gaussian(a=3)'};
            app.shape05.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.shape05.Visible = 'off';
            app.shape05.Value = 'Rect';
            app.length05 = uieditfield(app.uipanel12, 'text');
            app.length05.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.length05.Visible = 'off';
            app.length05.Value = '1';
            app.length04 = uieditfield(app.uipanel12, 'text');
            app.length04.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.length04.Visible = 'off';
            app.length04.Value = '1';
            app.shape04 = uidropdown(app.uipanel12);
            app.shape04.Items = {'Rect', 'Gaussian(a=2)', 'Gaussian(a=3)'};
            app.shape04.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.shape04.Visible = 'off';
            app.shape04.Value = 'Rect';
            app.idealflip04 = uieditfield(app.uipanel12, 'text');
            app.idealflip04.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.idealflip04.Visible = 'off';
            app.idealflip04.Value = '0';
            app.phase04 = uieditfield(app.uipanel12, 'text');
            app.phase04.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.phase04.Visible = 'off';
            app.phase04.Value = '0';
            app.phase03 = uieditfield(app.uipanel12, 'text');
            app.phase03.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.phase03.Visible = 'off';
            app.phase03.Value = '0';
            app.idealflip03 = uieditfield(app.uipanel12, 'text');
            app.idealflip03.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.idealflip03.Visible = 'off';
            app.idealflip03.Value = '0';
            app.shape03 = uidropdown(app.uipanel12);
            app.shape03.Items = {'Rect', 'Gaussian(a=2)', 'Gaussian(a=3)'};
            app.shape03.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.shape03.Visible = 'off';
            app.shape03.Value = 'Rect';
            app.length03 = uieditfield(app.uipanel12, 'text');
            app.length03.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.length03.Visible = 'off';
            app.length03.Value = '1';
            app.length02 = uieditfield(app.uipanel12, 'text');
            app.length02.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.length02.Visible = 'off';
            app.length02.Value = '1';
            app.idealflip02 = uieditfield(app.uipanel12, 'text');
            app.idealflip02.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.idealflip02.Visible = 'off';
            app.idealflip02.Value = '0';
            app.phase02 = uieditfield(app.uipanel12, 'text');
            app.phase02.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.phase02.Visible = 'off';
            app.phase02.Value = '0';
            app.phase01 = uieditfield(app.uipanel12, 'text');
            app.phase01.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.phase01.Visible = 'off';
            app.phase01.Value = '0';
            app.shape01 = uidropdown(app.uipanel12);
            app.shape01.Items = {'Rect', 'Gaussian (a=2)', 'Gaussian (a=3)', 'Sinc (3 lobes)', 'Sinc (5 lobes)'};
            app.shape01.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.shape01.Visible = 'off';
            app.shape01.Value = 'Rect';
            app.length01 = uieditfield(app.uipanel12, 'text');
            app.length01.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.length01.Visible = 'off';
            app.length01.Value = '1';
            app.text34 = uilabel(app.uipanel12);
            app.text34.Text = 'Phase(deg):';
            app.text33 = uilabel(app.uipanel12);
            app.text33.Text = 'Ideal flip angle(deg):';
            app.text32 = uilabel(app.uipanel12);
            app.text32.Text = 'Shape:';
            app.text31 = uilabel(app.uipanel12);
            app.text31.Text = 'Length(ms):';
            app.text41 = uilabel(app.uipanel12);
            app.text41.Text = 'Type:';
            app.grads01 = uieditfield(app.uipanel12, 'text');
            app.grads01.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.grads01.Visible = 'off';
            app.grads01.Value = '0';
            app.grads02 = uieditfield(app.uipanel12, 'text');
            app.grads02.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.grads02.Visible = 'off';
            app.grads02.Value = '0';
            app.grads03 = uieditfield(app.uipanel12, 'text');
            app.grads03.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.grads03.Visible = 'off';
            app.grads03.Value = '0';
            app.grads04 = uieditfield(app.uipanel12, 'text');
            app.grads04.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.grads04.Visible = 'off';
            app.grads04.Value = '0';
            app.grads05 = uieditfield(app.uipanel12, 'text');
            app.grads05.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.grads05.Visible = 'off';
            app.grads05.Value = '0';
            app.grads06 = uieditfield(app.uipanel12, 'text');
            app.grads06.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.grads06.Visible = 'off';
            app.grads06.Value = '0';
            app.grads07 = uieditfield(app.uipanel12, 'text');
            app.grads07.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.grads07.Visible = 'off';
            app.grads07.Value = '0';
            app.grads08 = uieditfield(app.uipanel12, 'text');
            app.grads08.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.grads08.Visible = 'off';
            app.grads08.Value = '0';
            app.grads09 = uieditfield(app.uipanel12, 'text');
            app.grads09.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.grads09.Visible = 'off';
            app.grads09.Value = '0';
            app.grads10 = uieditfield(app.uipanel12, 'text');
            app.grads10.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.grads10.Visible = 'off';
            app.grads10.Value = '0';
            app.text59 = uilabel(app.uipanel12);
            app.text59.Text = 'Grad Strength(mT/m):';
            app.idealflip01 = uieditfield(app.uipanel12, 'text');
            app.idealflip01.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.idealflip01.Visible = 'off';
            app.idealflip01.Value = '0';
            app.pphasecyc01 = uieditfield(app.uipanel12, 'text');
            app.pphasecyc01.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.pphasecyc01.Visible = 'off';
            app.pphasecyc01.Value = '0';
            app.pphasecyc02 = uieditfield(app.uipanel12, 'text');
            app.pphasecyc02.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.pphasecyc02.Visible = 'off';
            app.pphasecyc02.Value = '0';
            app.pphasecyc03 = uieditfield(app.uipanel12, 'text');
            app.pphasecyc03.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.pphasecyc03.Visible = 'off';
            app.pphasecyc03.Value = '0';
            app.pphasecyc04 = uieditfield(app.uipanel12, 'text');
            app.pphasecyc04.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.pphasecyc04.Visible = 'off';
            app.pphasecyc04.Value = '0';
            app.pphasecyc05 = uieditfield(app.uipanel12, 'text');
            app.pphasecyc05.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.pphasecyc05.Visible = 'off';
            app.pphasecyc05.Value = '0';
            app.pphasecyc06 = uieditfield(app.uipanel12, 'text');
            app.pphasecyc06.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.pphasecyc06.Visible = 'off';
            app.pphasecyc06.Value = '0';
            app.pphasecyc07 = uieditfield(app.uipanel12, 'text');
            app.pphasecyc07.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.pphasecyc07.Visible = 'off';
            app.pphasecyc07.Value = '0';
            app.pphasecyc08 = uieditfield(app.uipanel12, 'text');
            app.pphasecyc08.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.pphasecyc08.Visible = 'off';
            app.pphasecyc08.Value = '0';
            app.pphasecyc09 = uieditfield(app.uipanel12, 'text');
            app.pphasecyc09.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.pphasecyc09.Visible = 'off';
            app.pphasecyc09.Value = '0';
            app.pphasecyc10 = uieditfield(app.uipanel12, 'text');
            app.pphasecyc10.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.pphasecyc10.Visible = 'off';
            app.pphasecyc10.Value = '0';
            app.text67 = uilabel(app.uipanel12);
            app.text67.Text = 'Phase Cycle (deg):';
            app.PCAve = uieditfield(app.uipanel12, 'text');
            app.PCAve.Value = '1';
            app.shape02 = uidropdown(app.uipanel12);
            app.shape02.Items = {'Rect', 'Gaussian(a=2)', 'Gaussian(a=3)'};
            app.shape02.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.shape02.Visible = 'off';
            app.shape02.Value = 'Rect';
            app.TRep = uieditfield(app.uipanel12, 'text');
            app.TRep.ValueChangedFcn = createCallbackFcn(app, @TRepValueChanged, true);
            app.text75 = uilabel(app.uipanel12);
            app.text75.Text = 'TR:';
            app.step01 = uieditfield(app.uipanel12, 'text');
            app.step01.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.step01.Visible = 'off';
            app.step01.Value = '1';
            app.text85 = uilabel(app.uipanel12);
            app.text85.Text = 'Step:';
            app.step02 = uieditfield(app.uipanel12, 'text');
            app.step02.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.step02.Visible = 'off';
            app.step02.Value = '1';
            app.step03 = uieditfield(app.uipanel12, 'text');
            app.step03.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.step03.Visible = 'off';
            app.step03.Value = '1';
            app.step04 = uieditfield(app.uipanel12, 'text');
            app.step04.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.step04.Visible = 'off';
            app.step04.Value = '1';
            app.step05 = uieditfield(app.uipanel12, 'text');
            app.step05.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.step05.Visible = 'off';
            app.step05.Value = '1';
            app.step06 = uieditfield(app.uipanel12, 'text');
            app.step06.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.step06.Visible = 'off';
            app.step06.Value = '1';
            app.step07 = uieditfield(app.uipanel12, 'text');
            app.step07.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.step07.Visible = 'off';
            app.step07.Value = '1';
            app.step08 = uieditfield(app.uipanel12, 'text');
            app.step08.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.step08.Visible = 'off';
            app.step08.Value = '1';
            app.step09 = uieditfield(app.uipanel12, 'text');
            app.step09.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.step09.Visible = 'off';
            app.step09.Value = '1';
            app.step10 = uieditfield(app.uipanel12, 'text');
            app.step10.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.step10.Visible = 'off';
            app.step10.Value = '1';
            app.phase11 = uieditfield(app.uipanel12, 'text');
            app.phase11.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.phase11.Visible = 'off';
            app.phase11.Value = '0';
            app.idealflip11 = uieditfield(app.uipanel12, 'text');
            app.idealflip11.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.idealflip11.Visible = 'off';
            app.idealflip11.Value = '0';
            app.shape11 = uidropdown(app.uipanel12);
            app.shape11.Items = {'Rect', 'Gaussian(a=2)', 'Gaussian(a=3)'};
            app.shape11.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.shape11.Visible = 'off';
            app.shape11.Value = 'Rect';
            app.length11 = uieditfield(app.uipanel12, 'text');
            app.length11.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.length11.Visible = 'off';
            app.length11.Value = '1';
            app.grads11 = uieditfield(app.uipanel12, 'text');
            app.grads11.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.grads11.Visible = 'off';
            app.grads11.Value = '0';
            app.pphasecyc11 = uieditfield(app.uipanel12, 'text');
            app.pphasecyc11.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.pphasecyc11.Visible = 'off';
            app.pphasecyc11.Value = '0';
            app.step11 = uieditfield(app.uipanel12, 'text');
            app.step11.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.step11.Visible = 'off';
            app.step11.Value = '1';
            app.phase12 = uieditfield(app.uipanel12, 'text');
            app.phase12.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.phase12.Visible = 'off';
            app.phase12.Value = '0';
            app.idealflip12 = uieditfield(app.uipanel12, 'text');
            app.idealflip12.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.idealflip12.Visible = 'off';
            app.idealflip12.Value = '0';
            app.shape12 = uidropdown(app.uipanel12);
            app.shape12.Items = {'Rect', 'Gaussian(a=2)', 'Gaussian(a=3)'};
            app.shape12.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.shape12.Visible = 'off';
            app.shape12.Value = 'Rect';
            app.length12 = uieditfield(app.uipanel12, 'text');
            app.length12.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.length12.Visible = 'off';
            app.length12.Value = '1';
            app.grads12 = uieditfield(app.uipanel12, 'text');
            app.grads12.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.grads12.Visible = 'off';
            app.grads12.Value = '0';
            app.pphasecyc12 = uieditfield(app.uipanel12, 'text');
            app.pphasecyc12.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.pphasecyc12.Visible = 'off';
            app.pphasecyc12.Value = '0';
            app.step12 = uieditfield(app.uipanel12, 'text');
            app.step12.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.step12.Visible = 'off';
            app.step12.Value = '1';
            app.text44 = uilabel(app.uipanel12);
            app.text44.Text = 'PC Averages:';
            app.text53 = uilabel(app.uipanel12);
            app.text53.Text = 'Steady State Number:';
            app.steadystatenum = uieditfield(app.uipanel12, 'text');
            app.steadystatenum.Value = '1';
            app.pushbutton10 = uibutton(app.uipanel12, 'push');
            app.pushbutton10.Text = 'Adjust for TR';
            app.grad_lob = uieditfield(app.uipanel12, 'text');
            app.grad_lob.Value = '1';
            app.save_seq = uibutton(app.uipanel12, 'push');
            app.save_seq.ButtonPushedFcn = createCallbackFcn(app, @AppSaveSeq, true);
            app.save_seq.Text = 'Save Seq';
            app.Retreive_Seq = uibutton(app.uipanel12, 'push');
            app.Retreive_Seq.ButtonPushedFcn = createCallbackFcn(app, @AppLoadSeq, true);
            app.Retreive_Seq.Text = 'Retreive Seq';
            app.text95 = uilabel(app.uipanel12);
            app.text95.Text = 'Triple-S';
            app.pushbutton23 = uibutton(app.uipanel12, 'push');
            app.pushbutton23.Text = 'Calculate Gradients';
            app.text88 = uilabel(app.uipanel12);
            app.text88.Text = 'Gradient Dephase Lobes:';
            app.GradAve = uieditfield(app.uipanel12, 'text');
            app.GradAve.Value = '1';
            app.text111 = uilabel(app.uipanel12);
            app.text111.Text = 'Grad Averages:';
            app.type10 = uidropdown(app.uipanel12);
            app.type10.Items = {'Don''t Use', 'Delay', 'Pulse', 'Gradient', 'PulseGrad', 'Acquire'};
            app.type10.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.type10.Value = 'Don''t Use';
            app.type09 = uidropdown(app.uipanel12);
            app.type09.Items = {'Don''t Use', 'Delay', 'Pulse', 'Gradient', 'PulseGrad', 'Acquire'};
            app.type09.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.type09.Value = 'Don''t Use';
            app.type08 = uidropdown(app.uipanel12);
            app.type08.Items = {'Don''t Use', 'Delay', 'Pulse', 'Gradient', 'PulseGrad', 'Acquire'};
            app.type08.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.type08.Value = 'Don''t Use';
            app.type07 = uidropdown(app.uipanel12);
            app.type07.Items = {'Don''t Use', 'Delay', 'Pulse', 'Gradient', 'PulseGrad', 'Acquire'};
            app.type07.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.type07.Value = 'Don''t Use';
            app.type06 = uidropdown(app.uipanel12);
            app.type06.Items = {'Don''t Use', 'Delay', 'Pulse', 'Gradient', 'PulseGrad', 'Acquire'};
            app.type06.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.type06.Value = 'Don''t Use';
            app.type05 = uidropdown(app.uipanel12);
            app.type05.Items = {'Don''t Use', 'Delay', 'Pulse', 'Gradient', 'PulseGrad', 'Acquire'};
            app.type05.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.type05.Value = 'Don''t Use';
            app.type04 = uidropdown(app.uipanel12);
            app.type04.Items = {'Don''t Use', 'Delay', 'Pulse', 'Gradient', 'PulseGrad', 'Acquire'};
            app.type04.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.type04.Value = 'Don''t Use';
            app.type03 = uidropdown(app.uipanel12);
            app.type03.Items = {'Don''t Use', 'Delay', 'Pulse', 'Gradient', 'PulseGrad', 'Acquire'};
            app.type03.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.type03.Value = 'Don''t Use';
            app.type02 = uidropdown(app.uipanel12);
            app.type02.Items = {'Don''t Use', 'Delay', 'Pulse', 'Gradient', 'PulseGrad', 'Acquire'};
            app.type02.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.type02.Value = 'Don''t Use';
            app.type01 = uidropdown(app.uipanel12);
            app.type01.Items = {'Don''t Use', 'Delay', 'Pulse', 'Gradient', 'PulseGrad', 'Acquire'};
            app.type01.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.type01.Value = 'Don''t Use';
            app.type11 = uidropdown(app.uipanel12);
            app.type11.Items = {'Don''t Use', 'Delay', 'Pulse', 'Gradient', 'PulseGrad', 'Acquire'};
            app.type11.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.type11.Value = 'Don''t Use';
            app.type12 = uidropdown(app.uipanel12);
            app.type12.Items = {'Don''t Use', 'Delay', 'Pulse', 'Gradient', 'PulseGrad', 'Acquire'};
            app.type12.ValueChangedFcn = createCallbackFcn(app, @AppSetSequenceInterface, true);
            app.type12.Value = 'Don''t Use';
            app.SeqName = uieditfield(app.uipanel12, 'text');
            app.SeqName.Editable = 'off';
            app.text88_2 = uilabel(app.uipanel12);
            app.text88_2.Text = 'Sequence Name:';
            app.uipanel10 = uipanel(app.TripleS_Fig);
            app.uipanel10.Title = 'Domain Models to Solve';
            app.spin = uidropdown(app.uipanel10);
            app.spin.Items = {'Spin 3/2', 'Spin 1/2'};
            app.spin.Value = 'Spin 3/2';
            app.SDModel2 = uieditfield(app.uipanel10, 'text');
            app.SDModel2.Editable = 'off';
            app.SDModel2.Value = 'Not in Use';
            app.SDModel3 = uieditfield(app.uipanel10, 'text');
            app.SDModel3.Editable = 'off';
            app.SDModel3.Value = 'Not in Use';
            app.Load_Model1 = uibutton(app.uipanel10, 'push');
            app.Load_Model1.ButtonPushedFcn = createCallbackFcn(app, @AppLoadModel, true);
            app.Load_Model1.Text = 'Load';
            app.Unload_Model1 = uibutton(app.uipanel10, 'push');
            app.Unload_Model1.ButtonPushedFcn = createCallbackFcn(app, @AppUnloadModel, true);
            app.Unload_Model1.Text = 'Unload';
            app.Save_Model1 = uibutton(app.uipanel10, 'push');
            app.Save_Model1.ButtonPushedFcn = createCallbackFcn(app, @AppSaveModel, true);
            app.Save_Model1.Text = 'Save';
            app.New_Model1 = uibutton(app.uipanel10, 'push');
            app.New_Model1.ButtonPushedFcn = createCallbackFcn(app, @AppNewModel, true);
            app.New_Model1.Text = 'New';
            app.Load_Model2 = uibutton(app.uipanel10, 'push');
            app.Load_Model2.ButtonPushedFcn = createCallbackFcn(app, @AppLoadModel, true);
            app.Load_Model2.Text = 'Load';
            app.Unload_Model2 = uibutton(app.uipanel10, 'push');
            app.Unload_Model2.ButtonPushedFcn = createCallbackFcn(app, @AppUnloadModel, true);
            app.Unload_Model2.Text = 'Unload';
            app.Save_Model2 = uibutton(app.uipanel10, 'push');
            app.Save_Model2.ButtonPushedFcn = createCallbackFcn(app, @AppSaveModel, true);
            app.Save_Model2.Text = 'Save';
            app.New_Model2 = uibutton(app.uipanel10, 'push');
            app.New_Model2.ButtonPushedFcn = createCallbackFcn(app, @AppNewModel, true);
            app.New_Model2.Text = 'New';
            app.Load_Model3 = uibutton(app.uipanel10, 'push');
            app.Load_Model3.ButtonPushedFcn = createCallbackFcn(app, @AppLoadModel, true);
            app.Load_Model3.Text = 'Load';
            app.Unload_Model3 = uibutton(app.uipanel10, 'push');
            app.Unload_Model3.ButtonPushedFcn = createCallbackFcn(app, @AppUnloadModel, true);
            app.Unload_Model3.Text = 'Unload';
            app.Save_Model3 = uibutton(app.uipanel10, 'push');
            app.Save_Model3.ButtonPushedFcn = createCallbackFcn(app, @AppSaveModel, true);
            app.Save_Model3.Text = 'Save';
            app.SDModel1 = uieditfield(app.uipanel10, 'text');
            app.SDModel1.Editable = 'off';
            app.SDModel1.Value = 'Saline';
            app.New_Model3 = uibutton(app.uipanel10, 'push');
            app.New_Model3.ButtonPushedFcn = createCallbackFcn(app, @AppNewModel, true);
            app.New_Model3.Text = 'New';
            % Show the figure after all components are created
            app.TripleS_Fig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TripleS_2018_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TripleS_Fig)

            % Execute the startup function
            runStartupFcn(app, @AppStartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TripleS_Fig)
        end
    end
end